classdef montyHall < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Door1Button       matlab.ui.control.Button
        Door2Button       matlab.ui.control.Button
        Door3Button       matlab.ui.control.Button
        ResetButton       matlab.ui.control.Button
        ImageDoor1        matlab.ui.control.Image
        ImageDoor2        matlab.ui.control.Image
        ImageDoor3        matlab.ui.control.Image
        InstructionsText  matlab.ui.control.Label
    end

    
    properties (Access = private)
        State = "initial";% Description
        Game  = montyGame(3);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if ~isequal(app.State,"initial")
                app.State = "initial";
                app.Game = montyGame(3);
                
                app.Door1Button.Enable = true;
                app.Door2Button.Enable = true;
                app.Door3Button.Enable = true;
                
                app.ImageDoor1.ImageSource = 'door.png';
                app.ImageDoor2.ImageSource = 'door.png';
                app.ImageDoor3.ImageSource = 'door.png';
            app.InstructionsText.Text = {'Welcome to the Monty Hall game show. One of the three doors hides a prize', ...
                                         'The other two have goats. Choose a door you think contains the', ...
                                         'prize!'};
            end
        end

        % Button pushed function: Door1Button
        function Door1ButtonPushed(app, event)
            if isequal(app.State,"initial")
                % User chose this as the door they think hides the car.
                app.InstructionsText.Text = {'Alright. The host will now open one of the other doors', ...
                                             'that has a goat.'};
                app.Game = app.Game.openDoor(1);
                pause(5);
                openedDoor = app.Game.openedDoor;
                if openedDoor == 2
                    app.Door2Button.Enable = false;
                    app.ImageDoor2.ImageSource = 'goat.png';
                elseif openedDoor == 3
                    app.Door3Button.Enable = false;
                    app.ImageDoor3.ImageSource = 'goat.png';
                end
                app.InstructionsText.Text = {'Now, you have a choice. Do you stick to your original choice', ...
                                             'or switch? Click on door button of choice.'};
                app.State = "toRevealDoor";
            elseif isequal(app.State,"toRevealDoor")
                actualDoor = app.Game.actualDoor;
                if actualDoor == 1
                    app.ImageDoor1.ImageSource = 'sportscar.png';
                    app.ImageDoor2.ImageSource = 'goat.png';
                    app.ImageDoor3.ImageSource = 'goat.png';
                    app.InstructionsText.Text = {'Yay - you win! Press reset to play again!'};
                elseif actualDoor == 2
                    app.ImageDoor2.ImageSource = 'sportscar.png';
                    app.ImageDoor1.ImageSource = 'goat.png';
                    app.ImageDoor3.ImageSource = 'goat.png';                    
                    app.InstructionsText.Text = {'Wonk wonk - you win a goat. Press reset to play again!'};
                elseif actualDoor == 3
                    app.ImageDoor3.ImageSource = 'sportscar.png';
                    app.ImageDoor1.ImageSource = 'goat.png';
                    app.ImageDoor2.ImageSource = 'goat.png';
                    app.InstructionsText.Text = {'Wonk wonk - you win a goat. Press reset to play again!'};
                end
                app.Door1Button.Enable = false;
                app.Door2Button.Enable = false;
                app.Door3Button.Enable = false;                
            end
        end

        % Button pushed function: Door2Button
        function Door2ButtonPushed(app, event)
            if isequal(app.State,"initial")
                % User chose this as the door they think hides the car.
                app.InstructionsText.Text = {'Alright. The host will now open one of the other doors', ...
                                             'that has a goat.'};
                app.Game = app.Game.openDoor(2);
                pause(5);
                openedDoor = app.Game.openedDoor;
                if openedDoor == 1
                    app.Door1Button.Enable = false;
                    app.ImageDoor1.ImageSource = 'goat.png';
                elseif openedDoor == 3
                    app.Door3Button.Enable = false;
                    app.ImageDoor3.ImageSource = 'goat.png';
                end
                app.InstructionsText.Text = {'Now, you have a choice. Do you stick to your original choice', ...
                                             'or switch? Click on door button of choice.'};
                app.State = "toRevealDoor";
            elseif isequal(app.State,"toRevealDoor")
                actualDoor = app.Game.actualDoor;
                if actualDoor == 2
                    app.ImageDoor2.ImageSource = 'sportscar.png';
                    app.ImageDoor1.ImageSource = 'goat.png';
                    app.ImageDoor3.ImageSource = 'goat.png';
                    app.InstructionsText.Text = {'Yay - you win! Press reset to play again!'};
                elseif actualDoor == 1
                    app.ImageDoor1.ImageSource = 'sportscar.png';
                    app.ImageDoor2.ImageSource = 'goat.png';
                    app.ImageDoor3.ImageSource = 'goat.png';                    
                    app.InstructionsText.Text = {'Wonk wonk - you win a goat. Press reset to play again!'};
                elseif actualDoor == 3
                    app.ImageDoor3.ImageSource = 'sportscar.png';
                    app.ImageDoor1.ImageSource = 'goat.png';
                    app.ImageDoor2.ImageSource = 'goat.png';
                    app.InstructionsText.Text = {'Wonk wonk - you win a goat. Press reset to play again!'};
                end
                app.Door1Button.Enable = false;
                app.Door2Button.Enable = false;
                app.Door3Button.Enable = false;
            end            
        end

        % Button pushed function: Door3Button
        function Door3ButtonPushed(app, event)
            if isequal(app.State,"initial")
                % User chose this as the door they think hides the car.
                app.InstructionsText.Text = {'Alright. The host will now open one of the other doors', ...
                                             'that has a goat.'};
                app.Game = app.Game.openDoor(3);
                pause(5);
                openedDoor = app.Game.openedDoor;
                if openedDoor == 1
                    app.Door1Button.Enable = false;
                    app.ImageDoor1.ImageSource = 'goat.png';
                elseif openedDoor == 2
                    app.Door2Button.Enable = false;
                    app.ImageDoor2.ImageSource = 'goat.png';
                end
                app.InstructionsText.Text = {'Now, you have a choice. Do you stick to your original choice', ...
                                             'or switch? Click on door button of choice.'};
                app.State = "toRevealDoor";
            elseif isequal(app.State,"toRevealDoor")
                actualDoor = app.Game.actualDoor;
                if actualDoor == 3
                    app.ImageDoor3.ImageSource = 'sportscar.png';
                    app.ImageDoor2.ImageSource = 'goat.png';
                    app.ImageDoor1.ImageSource = 'goat.png';
                    app.InstructionsText.Text = {'Yay - you win! Press reset to play again!'};
                elseif actualDoor == 2
                    app.ImageDoor2.ImageSource = 'sportscar.png';
                    app.ImageDoor1.ImageSource = 'goat.png';
                    app.ImageDoor3.ImageSource = 'goat.png';                    
                    app.InstructionsText.Text = {'Wonk wonk - you win a goat. Press reset to play again!'};
                elseif actualDoor == 1
                    app.ImageDoor1.ImageSource = 'sportscar.png';
                    app.ImageDoor3.ImageSource = 'goat.png';
                    app.ImageDoor2.ImageSource = 'goat.png';
                    app.InstructionsText.Text = {'Wonk wonk - you win a goat. Press reset to play again!'};
                end
                app.Door1Button.Enable = false;
                app.Door2Button.Enable = false;
                app.Door3Button.Enable = false;                
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Door1Button = uibutton(app.UIFigure, 'push');
            app.Door1Button.ButtonPushedFcn = createCallbackFcn(app, @Door1ButtonPushed, true);
            app.Door1Button.Text = 'Door: 1';
            app.Door2Button = uibutton(app.UIFigure, 'push');
            app.Door2Button.ButtonPushedFcn = createCallbackFcn(app, @Door2ButtonPushed, true);
            app.Door2Button.Text = 'Door: 2';
            app.Door3Button = uibutton(app.UIFigure, 'push');
            app.Door3Button.ButtonPushedFcn = createCallbackFcn(app, @Door3ButtonPushed, true);
            app.Door3Button.Text = 'Door: 3';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ImageDoor1 = uiimage(app.UIFigure);
            app.ImageDoor1.ImageSource = 'door.png';
            app.ImageDoor2 = uiimage(app.UIFigure);
            app.ImageDoor2.ImageSource = 'door.png';
            app.ImageDoor3 = uiimage(app.UIFigure);
            app.ImageDoor3.ImageSource = 'door.png';
            app.InstructionsText = uilabel(app.UIFigure);
            app.InstructionsText.Text = {'Welcome to the Monty Hall game show. One of the three doors hides a prize '; 'The other two have goats. Choose a door you think contains the'; ' prize!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = montyHall

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end